classdef Brain_Cognition_Modulation_Task < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BrainCognitionandModulationTaskUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CONFIGTab                       matlab.ui.container.Tab
        defineCOMportformotordefaultCOM8Label  matlab.ui.control.Label
        nbPortEditField                 matlab.ui.control.EditField
        ThenumberoftrialsEditFieldLabel_2  matlab.ui.control.Label
        PlayButton                      matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        ExperimentOverviewLabel         matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        defineblankITIperiodinseconddefault15Label  matlab.ui.control.Label
        definefeedbackperiodinseconddefault20offline00Label  matlab.ui.control.Label
        t_feedbackEditField             matlab.ui.control.EditField
        FeedbackperiodsecEditFieldLabel  matlab.ui.control.Label
        definestimulationonsetperiodinseconddefault40Label  matlab.ui.control.Label
        definefixationcrossperiodinseconddefault15Label  matlab.ui.control.Label
        definehowmanytrialssubjectperformforeachblockLabel  matlab.ui.control.Label
        t_blankEditField                matlab.ui.control.EditField
        BlankITIperiodsecEditFieldLabel  matlab.ui.control.Label
        t_instructionEditField          matlab.ui.control.EditField
        StimulationperiodsecEditFieldLabel  matlab.ui.control.Label
        ProtocolButtonGroup             matlab.ui.container.ButtonGroup
        VibrotactileonlineButton        matlab.ui.control.RadioButton
        tCSButton                       matlab.ui.control.RadioButton
        OfflinecalibrationButton        matlab.ui.control.RadioButton
        OnlinetestButton                matlab.ui.control.RadioButton
        t_crossEditField                matlab.ui.control.EditField
        FixationperiodsecEditFieldLabel  matlab.ui.control.Label
        nbtrialEditField                matlab.ui.control.EditField
        ThenumberoftrialsEditFieldLabel  matlab.ui.control.Label
        DefineexperimentalparametersandplayLabel  matlab.ui.control.Label
        TRAINTab                        matlab.ui.container.Tab
        CSPchannelsButtonGroup          matlab.ui.container.ButtonGroup
        Central9chButton                matlab.ui.control.RadioButton
        Whole19chButton                 matlab.ui.control.RadioButton
        TextArea                        matlab.ui.control.TextArea
        TrainButton                     matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        defineeventmarkerforleftandrightrespectivelydefault12Label  matlab.ui.control.Label
        definethenumberofCSPfiltersdefault2Label  matlab.ui.control.Label
        definebandwidthdefault830Label  matlab.ui.control.Label
        definewindowlengthforclassificationdefault0535Label  matlab.ui.control.Label
        LeftrighteventmarkerEditField   matlab.ui.control.EditField
        LeftrighteventmarkerEditFieldLabel  matlab.ui.control.Label
        ThenumberofCSPfiltersEditField  matlab.ui.control.EditField
        ThenumberofCSPfiltersEditFieldLabel  matlab.ui.control.Label
        BandpassfilteringHzEditField    matlab.ui.control.EditField
        BandpassfilteringHzEditFieldLabel  matlab.ui.control.Label
        WindowlengthminsecEditField     matlab.ui.control.EditField
        WindowlengthminsecEditFieldLabel  matlab.ui.control.Label
        TrainingParametersLabel         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TrainandvisualizeamodelLabel    matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        BrainCognitionandModulationTaskLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MI_Cursor_Params = struct('nbtrial', 4, 't_cross', 1.5, ...
            't_instruction', 4.0, 't_feedback', 2.0, 't_blank', 1.5, 'online', 'off'); % Parameters for MI-BCI Cursor task, structure
        fnames_for_train = cell(0); % file names for training
        MI_train_Params = struct('frame', [0.5 3.5], 'bp', [8 30], 'nCSP', 2, 'Markers_defined', [1 2]); % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % load or add prerequisits
            addpath('.\images\');
            addpath('.\functions\');
            addpath('.\images_MI\');

        end

        % Callback function
        function UIAxesButtonDown(app, event)
            set(0, 'ShowHiddenHandles', 'on');
            implay('StarStim8.mp4', 30);
            fig_handle = gcf;
            set(gcf, 'position', [0 0 1600 900]);
            ftw = fig_handle.findobj('TooltipString', 'Maintain fit to window');
%             ftw.ClickedCallback();
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            app.MI_Cursor_Params.nbtrial = str2double(app.nbtrialEditField.Value);
            app.MI_Cursor_Params.t_cross = str2double(app.t_crossEditField.Value);
            app.MI_Cursor_Params.t_instruction = str2double(app.t_instructionEditField.Value);
            app.MI_Cursor_Params.t_feedback = str2double(app.t_feedbackEditField.Value);
            app.MI_Cursor_Params.t_blank = str2double(app.t_blankEditField.Value);
            app.MI_Cursor_Params.paradigm = [];
            if app.OnlinetestButton.Value==1
                app.MI_Cursor_Params.online = 'on';
            elseif app.OfflinecalibrationButton.Value ==1
                app.MI_Cursor_Params.online = 'off';
            elseif app.tCSButton.Value == 1
                app.MI_Cursor_Params.paradigm = 'tCS';
                app.MI_Cursor_Params.online = 'off';
            elseif app.VibrotactileonlineButton.Value == 1
                app.MI_Cursor_Params.paradigm = 'VT';
                app.MI_Cursor_Params.port = app.nbPortEditField.Value;
            end
            disp('Experimenta Parameters:');
            disp(app.MI_Cursor_Params);
            
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            % load training data and run CSP-FLDA train
            [baseName, folder] = uigetfile('*.*', 'MultiSelect','on');
            app.fnames_for_train = fullfile(folder, baseName);
            
            app.UIAxes.Visible = 'on';
            app.UIAxes_2.Visible = 'on';
            LDA_trained = train_CSPFLDA_mdl(app.UIAxes,app.UIAxes_2, app.MI_train_Params, app.fnames_for_train);
            app.TextArea.Value = strtrim(strsplit(evalc('disp(LDA_trained)')));
            app.TextArea.Visible = 'on';
            set(app.BrainCognitionandModulationTaskUIFigure, 'color', [0.15,0.15,0.15]);
            
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            % save the parameters for training CSP-FLDA
           app.MI_train_Params.frame = str2num(app.WindowlengthminsecEditField.Value);
           app.MI_train_Params.bp = str2num(app.BandpassfilteringHzEditField.Value);
           app.MI_train_Params.nCSP = str2num(app.ThenumberofCSPfiltersEditField.Value);
           app.MI_train_Params.Markers_defined = str2num(app.LeftrighteventmarkerEditField.Value);
           if app.Central9chButton.Value == 1
               app.MI_train_Params.chCSP = 1:9;
           elseif app.Whole19chButton == 1
               app.MI_train_Params.chCSP = 1:19;
           end
           disp('Training CSP-FLDA Parameters:');
           disp(app.MI_train_Params);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            set(app.BrainCognitionandModulationTaskUIFigure, 'windowstate', 'minimized');
            
            if (strcmpi(app.MI_Cursor_Params.paradigm, 'VT'))
                func_MI_cursorTask_VT(app.MI_Cursor_Params);
            elseif (strcmpi(app.MI_Cursor_Params.paradigm, 'tCS'))
                func_MI_cursorTask_tCS(app.MI_Cursor_Params);
            else
                func_MI_cursorTask_debug(app.MI_Cursor_Params);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BrainCognitionandModulationTaskUIFigure = uifigure('Visible', 'off');
            app.BrainCognitionandModulationTaskUIFigure.Name = 'Brain Cognition and Modulation Task';
            app.BrainCognitionandModulationTaskLabel = uilabel(app.BrainCognitionandModulationTaskUIFigure);
            app.BrainCognitionandModulationTaskLabel.Text = 'Brain Cognition and Modulation Task';
            app.TabGroup = uitabgroup(app.BrainCognitionandModulationTaskUIFigure);
            app.CONFIGTab = uitab(app.TabGroup);
            app.CONFIGTab.Title = 'CONFIG';
            app.DefineexperimentalparametersandplayLabel = uilabel(app.CONFIGTab);
            app.DefineexperimentalparametersandplayLabel.Text = 'Define experimental parameters and play';
            app.ThenumberoftrialsEditFieldLabel = uilabel(app.CONFIGTab);
            app.ThenumberoftrialsEditFieldLabel.Text = 'The number of trials';
            app.nbtrialEditField = uieditfield(app.CONFIGTab, 'text');
            app.nbtrialEditField.Value = '40';
            app.FixationperiodsecEditFieldLabel = uilabel(app.CONFIGTab);
            app.FixationperiodsecEditFieldLabel.Text = 'Fixation period (sec.)';
            app.t_crossEditField = uieditfield(app.CONFIGTab, 'text');
            app.t_crossEditField.Value = '1.5';
            app.ProtocolButtonGroup = uibuttongroup(app.CONFIGTab);
            app.ProtocolButtonGroup.Title = 'Protocol';
            app.OnlinetestButton = uiradiobutton(app.ProtocolButtonGroup);
            app.OnlinetestButton.Text = 'Online (test)';
            app.OfflinecalibrationButton = uiradiobutton(app.ProtocolButtonGroup);
            app.OfflinecalibrationButton.Text = 'Offline (calibration)';
            app.OfflinecalibrationButton.Value = true;
            app.tCSButton = uiradiobutton(app.ProtocolButtonGroup);
            app.tCSButton.Text = 'tCS';
            app.VibrotactileonlineButton = uiradiobutton(app.ProtocolButtonGroup);
            app.VibrotactileonlineButton.Text = 'Vibrotactile (online)';
            app.StimulationperiodsecEditFieldLabel = uilabel(app.CONFIGTab);
            app.StimulationperiodsecEditFieldLabel.Text = 'Stimulation period (sec.)';
            app.t_instructionEditField = uieditfield(app.CONFIGTab, 'text');
            app.t_instructionEditField.Value = '4.0';
            app.BlankITIperiodsecEditFieldLabel = uilabel(app.CONFIGTab);
            app.BlankITIperiodsecEditFieldLabel.Text = 'Blank (ITI) period (sec.)';
            app.t_blankEditField = uieditfield(app.CONFIGTab, 'text');
            app.t_blankEditField.Value = '1.5';
            app.definehowmanytrialssubjectperformforeachblockLabel = uilabel(app.CONFIGTab);
            app.definehowmanytrialssubjectperformforeachblockLabel.Text = '# define how many trials subject perform for each block';
            app.definefixationcrossperiodinseconddefault15Label = uilabel(app.CONFIGTab);
            app.definefixationcrossperiodinseconddefault15Label.Text = '# define fixation (cross) period in second. (default: 1.5)';
            app.definestimulationonsetperiodinseconddefault40Label = uilabel(app.CONFIGTab);
            app.definestimulationonsetperiodinseconddefault40Label.Text = '# define stimulation (onset) period in second. (default: 4.0)';
            app.FeedbackperiodsecEditFieldLabel = uilabel(app.CONFIGTab);
            app.FeedbackperiodsecEditFieldLabel.Text = 'Feedback period (sec.)';
            app.t_feedbackEditField = uieditfield(app.CONFIGTab, 'text');
            app.t_feedbackEditField.Value = '2.0';
            app.definefeedbackperiodinseconddefault20offline00Label = uilabel(app.CONFIGTab);
            app.definefeedbackperiodinseconddefault20offline00Label.Text = '# define feedback period in second. (default: 2.0, offline: 0.0)';
            app.defineblankITIperiodinseconddefault15Label = uilabel(app.CONFIGTab);
            app.defineblankITIperiodinseconddefault15Label.Text = '# define blank (ITI) period in second. (default: 1.5)';
            app.Image3 = uiimage(app.CONFIGTab);
            app.Image3.ImageSource = 'summary.png';
            app.ExperimentOverviewLabel = uilabel(app.CONFIGTab);
            app.ExperimentOverviewLabel.Text = 'Experiment Overview';
            app.ApplyButton = uibutton(app.CONFIGTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.PlayButton = uibutton(app.CONFIGTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ThenumberoftrialsEditFieldLabel_2 = uilabel(app.CONFIGTab);
            app.ThenumberoftrialsEditFieldLabel_2.Text = 'COM Port (VT)';
            app.nbPortEditField = uieditfield(app.CONFIGTab, 'text');
            app.nbPortEditField.Value = 'COM8';
            app.defineCOMportformotordefaultCOM8Label = uilabel(app.CONFIGTab);
            app.defineCOMportformotordefaultCOM8Label.Text = '# define COM port for motor (default: COM8)';
            app.TRAINTab = uitab(app.TabGroup);
            app.TRAINTab.Title = 'TRAIN';
            app.UIAxes = uiaxes(app.TRAINTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.TRAINTab);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.Visible = 'off';
            app.TrainandvisualizeamodelLabel = uilabel(app.TRAINTab);
            app.TrainandvisualizeamodelLabel.Text = 'Train and visualize a model';
            app.Label = uilabel(app.TRAINTab);
            app.Label.Text = {'- Currently, it trains CSP-FLDA model using the selected data and draws 1st and last CSP filters'; '- Define training parameters and load data for training'; '- Save the trained model'};
            app.TrainingParametersLabel = uilabel(app.TRAINTab);
            app.TrainingParametersLabel.Text = 'Training Parameters';
            app.WindowlengthminsecEditFieldLabel = uilabel(app.TRAINTab);
            app.WindowlengthminsecEditFieldLabel.Text = 'Window length [min] (sec.)';
            app.WindowlengthminsecEditField = uieditfield(app.TRAINTab, 'text');
            app.WindowlengthminsecEditField.Value = '0.5 3.5';
            app.BandpassfilteringHzEditFieldLabel = uilabel(app.TRAINTab);
            app.BandpassfilteringHzEditFieldLabel.Text = 'Band pass filtering (Hz)';
            app.BandpassfilteringHzEditField = uieditfield(app.TRAINTab, 'text');
            app.BandpassfilteringHzEditField.Value = '8 30';
            app.ThenumberofCSPfiltersEditFieldLabel = uilabel(app.TRAINTab);
            app.ThenumberofCSPfiltersEditFieldLabel.Text = 'The number of CSP filters';
            app.ThenumberofCSPfiltersEditField = uieditfield(app.TRAINTab, 'text');
            app.ThenumberofCSPfiltersEditField.Value = '1';
            app.LeftrighteventmarkerEditFieldLabel = uilabel(app.TRAINTab);
            app.LeftrighteventmarkerEditFieldLabel.Text = 'Left/right event marker';
            app.LeftrighteventmarkerEditField = uieditfield(app.TRAINTab, 'text');
            app.LeftrighteventmarkerEditField.Value = '1 2';
            app.definewindowlengthforclassificationdefault0535Label = uilabel(app.TRAINTab);
            app.definewindowlengthforclassificationdefault0535Label.Text = '# define window length for classification (default: 0.5 3.5)';
            app.definebandwidthdefault830Label = uilabel(app.TRAINTab);
            app.definebandwidthdefault830Label.Text = '# define bandwidth (default: 8 30)';
            app.definethenumberofCSPfiltersdefault2Label = uilabel(app.TRAINTab);
            app.definethenumberofCSPfiltersdefault2Label.Text = '# define the number of CSP filters (default: 2)';
            app.defineeventmarkerforleftandrightrespectivelydefault12Label = uilabel(app.TRAINTab);
            app.defineeventmarkerforleftandrightrespectivelydefault12Label.Text = '# define event marker for left and right, respectively (default: 1 2)';
            app.SetButton = uibutton(app.TRAINTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.TrainButton = uibutton(app.TRAINTab, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.TextArea = uitextarea(app.TRAINTab);
            app.TextArea.Visible = 'off';
            app.CSPchannelsButtonGroup = uibuttongroup(app.TRAINTab);
            app.CSPchannelsButtonGroup.Title = 'CSP channels';
            app.Whole19chButton = uiradiobutton(app.CSPchannelsButtonGroup);
            app.Whole19chButton.Text = 'Whole (19ch)';
            app.Central9chButton = uiradiobutton(app.CSPchannelsButtonGroup);
            app.Central9chButton.Text = 'Central (9ch)';
            app.Central9chButton.Value = true;
            % Show the figure after all components are created
            app.BrainCognitionandModulationTaskUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Brain_Cognition_Modulation_Task

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BrainCognitionandModulationTaskUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BrainCognitionandModulationTaskUIFigure)
        end
    end
end